classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        logo_truong            matlab.ui.control.Image
        ThongTin               matlab.ui.control.Label
        avatar                 matlab.ui.control.Image
        BaoCaoCuoiKy           matlab.ui.control.Label
        PhuongPhap             matlab.ui.control.DropDown
        BaiTap3_2              matlab.ui.control.Button
        RunButton              matlab.ui.control.Button
        GioiHan                matlab.ui.control.EditField
        Data                   matlab.ui.control.EditField
        BaiTap4_1              matlab.ui.control.Button
        BaiTap5_7              matlab.ui.control.Button
        BaiTap6_3              matlab.ui.control.Button
        logo_khoa              matlab.ui.control.Image
        Nhap_A                 matlab.ui.control.EditField
        Nhap_b                 matlab.ui.control.EditField
        NhpAhocxLabel          matlab.ui.control.Label
        NhpbhocyLabel          matlab.ui.control.Label
        HmhocdliucnxlLabel     matlab.ui.control.Label
        GiihnabLabel           matlab.ui.control.Label
        KtqutnhtonLabel        matlab.ui.control.Label
        KetQuaTinhToan         matlab.ui.control.TextArea
        Hamlap                 matlab.ui.control.EditField
        HmlpchophngphplpLabel  matlab.ui.control.Label
        DeBai                  matlab.ui.control.TextArea
        biLabel                matlab.ui.control.Label
        GiaTri                 matlab.ui.control.EditField
        GitrSaisLabel          matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        try
            app.KetQuaTinhToan.Value = '';
            switch app.PhuongPhap.Value
                case 'Lặp đơn'
                    fx = app.Data.Value;
                    fx = str2func(['@(x)' fx]);
                    fLap = app.Hamlap.Value;
                    fLap = str2func(['@(x)' fLap]);
                    Seg = app.GioiHan.Value;
                    if (Seg == "")
                        msgbox({'Không để trống!',' Vui lòng nhập khoảng giới hạn'},'Error','error');
                    else
                    Seg =str2num(Seg);
                    saiso = app.GiaTri.Value;
                    if (saiso == "")
                        msgbox({'Không để trống!',' Vui lòng nhập giá trị sai số'},'Error','error');
                    else
                    saiso = str2num(saiso);
                    [nghiem solanlap] = lap(fLap,Seg(1),Seg(2),saiso);
                    x = 0:0.01:10;
                    y = fx(x);
                    plot(app.UIAxes,x,y);
                    text(app.UIAxes,nghiem,0,'x');
                    app.KetQuaTinhToan.Value = ['Nghiệm gần đúng của phương trình là:' num2str(nghiem) ' và Số lần lặp là: ' num2str(solanlap)];
                    end
                    end
                    case 'Tiếp tuyến'
                    fx = app.Data.Value;
                    fx = str2func(['@(x)' fx]);
                    Seg = app.GioiHan.Value;
                    if (Seg == "")
                        msgbox({'Không để trống!',' Vui lòng nhập khoảng giới hạn'},'Error','error');
                    else
                    Seg =str2num(Seg);
                    saiso = app.GiaTri.Value;
                    if (saiso == "")
                        msgbox({'Không để trống!',' Vui lòng nhập giá trị sai số'},'Error','error');
                    else
                    saiso = str2num(saiso);
                    [nghiem solanlap] = tieptuyen(fx,Seg(1),Seg(2),saiso);
                    x = 0:0.01:10;
                    y = fx(x);
                    plot(app.UIAxes,x,y);
                    text(app.UIAxes,nghiem,0,'x');
                    app.KetQuaTinhToan.Value = ['Nghiệm gần đúng của phương trình là:' num2str(nghiem) ' và Số lần lặp là: ' num2str(solanlap)];
                    end
                    end
                case 'Dây cung'
                    fx = app.Data.Value;
                    fx = str2func(['@(x)' fx]);
                    Seg = app.GioiHan.Value;
                    if (Seg == "")
                        msgbox({'Không để trống!',' Vui lòng nhập khoảng giới hạn'},'Error','error');
                    else
                    Seg =str2num(Seg);
                    saiso = app.GiaTri.Value;
                    if (saiso == "")
                        msgbox({'Không để trống!',' Vui lòng nhập giá trị sai số'},'Error','error');
                    else
                    saiso = str2num(saiso);
                    [nghiem solanlap] = secant(fx,Seg(1),Seg(2),saiso);
                    x = 0:0.01:10;
                    y = fx(x);
                    plot(app.UIAxes,x,y);
                    text(app.UIAxes,nghiem,0,'x');
                    app.KetQuaTinhToan.Value = ['Nghiệm gần đúng của phương trình là:' num2str(nghiem) ' và Số lần lặp là: ' num2str(solanlap)];
                    end
                    end
                 case 'Gauss-Jordan'
                    A = app.Nhap_A.Value;
                    b = app.Nhap_b.Value;
                    if (A == "") || (b == "")
                        msgbox({'Không để trống!','Vùi lòng nhập dữ liệu để giải hệ phương trình.'},'Error','error');
                    else
                    A = str2num(A);
                    b = str2num(b);
                    nghiem = (gauss_jordan(A,b))';
                    nghiem = num2str(nghiem);
                    nghiem = "{ " + nghiem + " }";
                    app.KetQuaTinhToan.Value = ['Nghiệm của hệ phương trình là: ' num2str(nghiem)];
                    end
                    case 'Newton'
                    x = app.Nhap_A.Value;
                    y = app.Nhap_b.Value;
                    if (x == "") || (y == "")
                        msgbox({'Không để trống!','Vùi lòng nhập dữ liệu để tìm đa thức nội suy.'},'Error','error');
                    else
                    x = str2num(x);
                    y = str2num(y);
                    No = app.Data.Value;
                    if (No == "")
                        msgbox("Vùi lòng nhập giá trị nội suy",'Error','error');
                    else
                    No = str2num(No);
                    [dathuc Yn] = noisuy_newton(x,y,No);
                    j = 1;
                    a = 0:0.01:(x(length(x)) - x(1))/(x(2) - x(1));
                    for i = 0:0.01:(x(length(x)) - x(1))/(x(2) - x(1))
                        b(j) = dathuc(i);
                        j = j + 1;
                    end
                    plot(app.UIAxes,a,b);
                    dathuc = func2str(dathuc);
                    Yn = num2str(Yn);
                    if contains(dathuc,'@(t)')
                        dathuc = strrep(dathuc,'@(t)','');
                    end
                    app.KetQuaTinhToan.Value = ['Đa thức nội suy Newton là: ' dathuc ' và Giá trị nội suy là ' Yn];
                    end
                    end
                case 'Hình thang'
                    fx = app.Data.Value;
                    fx = str2func(['@(x)' fx]);
                    Seg = app.GioiHan.Value;
                    if (Seg == "")
                        msgbox({'Không để trống!',' Vui lòng nhập khoảng giới hạn'},'Error','error');
                    else
                    Seg = str2num(Seg);
                    N = app.GiaTri.Value;
                    if (N == "")
                        msgbox({'Không để trống!',' Vui lòng nhập giá trị số đoạn con.'},'Error','error');
                    else
                    N = str2num(N);
                    if (N < 1) 
                        msgbox({'Dùng sai phương pháp','Vui lòng chọn phương pháp phù hợp để giải!'},'Error','error');
                    else
                    I = tichphanhinhthang(fx,Seg(1),Seg(2),N);
                    I = num2str(I);
                    x = 0:0.01:10;
                    y = fx(x);
                    plot(app.UIAxes,x,y);
                    line(app.UIAxes,[Seg(1) Seg(1)], [0 fx(Seg(1))],'Color','black','linewidth',2);
                    line(app.UIAxes,[Seg(2) Seg(2)], [0 fx(Seg(2))],'Color','black','linewidth',2);
                    app.KetQuaTinhToan.Value = ['Kết quả tích phân là: ' I];
                    end
                    end
                    end
                otherwise
            end
        catch error 
            switch (error.identifier)
                case {'MATLAB:badsubscript','MATLAB:m_illegal_character','MATLAB:dimagree','MATLAB:catenate:dimensionMismatch'}
                    msgbox({'Dùng sai phương pháp','Vui lòng chọn phương pháp phù hợp để giải!'},'Error','error');
                case 'MATLAB:m_incomplete_statement'
                    msgbox({'Không để trống','Vùi lòng nhập hàm/hàm lặp hoặc dữ liệu cần xử lý'},'Error','error');
                otherwise 
                    msgbox({error.identifier,error.message},'Error','error');
            end
            
        end  
        end

        % Button pushed function: BaiTap3_2
        function BaiTap3_2ButtonPushed(app, event)
            app.Data.Value = 'x.^3 - 12*x - 5';
            app.Hamlap.Value = '(5+12.*x).^(1/3)';
            app.GioiHan.Value = '[3 4]';
            app.Nhap_A.Value = 'Không nhập';
            app.Nhap_b.Value = 'Không nhập';
            app.GiaTri.Value = '0.01';
            app.KetQuaTinhToan.Value = '';
            app.DeBai.Value = 'Bằng phương pháp lăp đơn, phương pháp Newton, và phương pháp dây cung tìm nghiệm gần đúng của phương trình x.^3 -12.*x - 5 = 0 sao cho đạt sai số tuyệt đối < 0.01';
            plot(app.UIAxes,0,0);
            
        end

        % Button pushed function: BaiTap4_1
        function BaiTap4_1ButtonPushed(app, event)
            app.Nhap_A.Value = '[1.7 0.2 0.1 0.3;0.2 1.9 -0.1 0.2;0.1 -0.1 1.3 -0.4;0.3 0.2 -0.4 4.1]';
            app.Nhap_b.Value = '[1.6 0.3 -1.2 0.7]';
            app.Data.Value = 'Không nhập';
            app.Hamlap.Value = 'Không nhập';
            app.GioiHan.Value = 'Không nhập';
            app.GiaTri.Value = 'Không nhập';
            app.KetQuaTinhToan.Value = '';
            app.DeBai.Value = 'Giải hê phương trình Ax = b bằng phương pháp Gauss-Jordan vơi A, b đã được nhập sẳn.'
            plot(app.UIAxes,0,0);
        end

        % Button pushed function: BaiTap5_7
        function BaiTap5_7ButtonPushed(app, event)
            app.Nhap_A.Value = '[58	58.34	58.68	59.02	59.36	59.7]';
            app.Nhap_b.Value = '[4303.52	4364.11	4425.17	4486.69	4548.69	4611.16]';
            app.Data.Value = '58.17';
            app.Hamlap.Value = 'Không nhập';
            app.GioiHan.Value = 'Không nhập';
            app.GiaTri.Value = 'Không nhập';
            app.KetQuaTinhToan.Value = '';
            app.DeBai.Value = 'Xây dưng công thức nội suv Newton với dãy x, y đã được nhập sẳn, tại x = 58.17';
            plot(app.UIAxes,0,0);
        end

        % Button pushed function: BaiTap6_3
        function BaiTap6_3ButtonPushed(app, event)
            app.Data.Value = 'sqrt(x)';
            app.GioiHan.Value = '[1,2]';
            app.GiaTri.Value = '10';
            app.Hamlap.Value = 'Không nhập';
            app.Nhap_A.Value = 'Không nhập';
            app.Nhap_b.Value = 'Không nhập';
            app.KetQuaTinhToan.Value = '';
            app.DeBai.Value = 'Tính gần đúng tích phân xác định: fx = sqrt(x), a = 1, b = 2, N =10';
            plot(app.UIAxes,0,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.logo_truong = uiimage(app.UIFigure);
            app.logo_truong.ImageSource = 'logo.png';
            app.ThongTin = uilabel(app.UIFigure);
            app.ThongTin.Text = {' Lớp: 20DTV1'; ' Họ và tên SV: Nguyễn Dũng'; ' Mã số SV: 20200121'};
            app.avatar = uiimage(app.UIFigure);
            app.avatar.ImageSource = 'NguyễnDũng.jpg';
            app.BaoCaoCuoiKy = uilabel(app.UIFigure);
            app.BaoCaoCuoiKy.Text = {'BÁO CÁO CUỐI KỲ'; 'Môn học: Phương pháp tính và Matlab.'; ''};
            app.PhuongPhap = uidropdown(app.UIFigure);
            app.PhuongPhap.Items = {'Phương pháp', 'Lặp đơn', 'Tiếp tuyến', 'Dây cung', 'Gauss-Jordan', 'Newton', 'Hình thang'};
            app.PhuongPhap.Value = 'Phương pháp';
            app.BaiTap3_2 = uibutton(app.UIFigure, 'push');
            app.BaiTap3_2.ButtonPushedFcn = createCallbackFcn(app, @BaiTap3_2ButtonPushed, true);
            app.BaiTap3_2.Text = 'Bài tập 3.2 (bài 1)/Chương 8';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.GioiHan = uieditfield(app.UIFigure, 'text');
            app.Data = uieditfield(app.UIFigure, 'text');
            app.BaiTap4_1 = uibutton(app.UIFigure, 'push');
            app.BaiTap4_1.ButtonPushedFcn = createCallbackFcn(app, @BaiTap4_1ButtonPushed, true);
            app.BaiTap4_1.Text = 'Bài tập 4.1 (bài 4,5,6)/Chương 9';
            app.BaiTap5_7 = uibutton(app.UIFigure, 'push');
            app.BaiTap5_7.ButtonPushedFcn = createCallbackFcn(app, @BaiTap5_7ButtonPushed, true);
            app.BaiTap5_7.Text = 'Bài tập 5.7/Chương 10';
            app.BaiTap6_3 = uibutton(app.UIFigure, 'push');
            app.BaiTap6_3.ButtonPushedFcn = createCallbackFcn(app, @BaiTap6_3ButtonPushed, true);
            app.BaiTap6_3.Text = 'Bài tập 6.3/Chương 11 12';
            app.logo_khoa = uiimage(app.UIFigure);
            app.logo_khoa.ImageSource = 'logo-fetel.png';
            app.Nhap_A = uieditfield(app.UIFigure, 'text');
            app.Nhap_b = uieditfield(app.UIFigure, 'text');
            app.NhpAhocxLabel = uilabel(app.UIFigure);
            app.NhpAhocxLabel.Text = 'Nhập A(hoặc x):';
            app.NhpbhocyLabel = uilabel(app.UIFigure);
            app.NhpbhocyLabel.Text = 'Nhập b(hoặc y):';
            app.HmhocdliucnxlLabel = uilabel(app.UIFigure);
            app.HmhocdliucnxlLabel.Text = 'Hàm hoặc dữ liệu cần xử lý';
            app.GiihnabLabel = uilabel(app.UIFigure);
            app.GiihnabLabel.Text = 'Giới hạn [a.b] ';
            app.KtqutnhtonLabel = uilabel(app.UIFigure);
            app.KtqutnhtonLabel.Text = 'Kết quả tính toán';
            app.KetQuaTinhToan = uitextarea(app.UIFigure);
            app.Hamlap = uieditfield(app.UIFigure, 'text');
            app.HmlpchophngphplpLabel = uilabel(app.UIFigure);
            app.HmlpchophngphplpLabel.Text = 'Hàm lặp (cho phương pháp lặp):';
            app.DeBai = uitextarea(app.UIFigure);
            app.biLabel = uilabel(app.UIFigure);
            app.biLabel.Text = 'Đề bài:';
            app.GiaTri = uieditfield(app.UIFigure, 'text');
            app.GitrSaisLabel = uilabel(app.UIFigure);
            app.GitrSaisLabel.Text = 'Giá trị/Sai số';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end